classdef Air_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AirInterface5GLabel             matlab.ui.control.Label
        OFDMPanel                       matlab.ui.container.Panel
        GenerateButton                  matlab.ui.control.Button
        InsertDCNullCheckBox            matlab.ui.control.CheckBox
        WindowingCheckBox               matlab.ui.control.CheckBox
        PioltInputCheckBox              matlab.ui.control.CheckBox
        FFTLengthEditFieldLabel         matlab.ui.control.Label
        FFTLengthEditField              matlab.ui.control.NumericEditField
        CyclicPrefixLengthEditFieldLabel  matlab.ui.control.Label
        CyclicPrefixLengthEditField     matlab.ui.control.NumericEditField
        OFDMSymbolsEditFieldLabel       matlab.ui.control.Label
        OFDMSymbolsEditField            matlab.ui.control.NumericEditField
        TransmitAntennasEditFieldLabel  matlab.ui.control.Label
        TransmitAntennasEditField       matlab.ui.control.NumericEditField
        SubcarrierSpacingHzEditFieldLabel  matlab.ui.control.Label
        SubcarrierSpacingHzEditField    matlab.ui.control.NumericEditField
        OFDMModelPanel                  matlab.ui.container.Panel
        AddSignalDisruptionPanel        matlab.ui.container.Panel
        Signal1CheckBox                 matlab.ui.control.CheckBox
        Signal2CheckBox                 matlab.ui.control.CheckBox
        Signal3CheckBox                 matlab.ui.control.CheckBox
        Signal4CheckBox                 matlab.ui.control.CheckBox
        Button                          matlab.ui.control.Button
        CDMAPanel                       matlab.ui.container.Panel
        genButton                       matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Pax 
    end
    
    methods (Access = public)
        
        function test(app)
           hMod = comm.OFDMModulator;
           hMod.FFTLength = 128;
           hMod.NumSymbols = 2;
           
           disp(hMod)
           
           showResourceMapping(hMod)
        end
        
        function test2(app)
            %sine = dsp.SineWave(amp,freq,phase,Name,Value)
            sine1 = dsp.SineWave();
            sine1.Amplitude = app.EditAmplitude.Value;
            sine1.Frequency = app.EditFrequency.Value;
            sine1.PhaseOffset = app.EditPhaseOffset.Value * pi/2;
            sine1.SamplesPerFrame = app.EditSamplesPerFrame.Value;
            y = sine1();
            
            plot(app.UIAxes , y );
            
        end
        
        function test3(app)

            hMod = comm.OFDMModulator;

            hMod.FFTLength = 128;
            hMod.NumSymbols = 2;

            hMod.PilotInputPort = true;

            hMod.PilotCarrierIndices = [12; 56; 89; 100];

            hMod.NumTransmitAntennas = 1 ;

            disp(hMod);
            showResourceMapping(hMod);

            %gx = uiaxes("Parent", app.OFDMPanel);

        %    plot(app.OFDMPanel , gx);
        %    p = uipanel;
        %    p = uipanel(app.OFDMPanel);

        %    app.OFDMPanel(geoaxes);

        end
        
        function test4(app)
            
        %% Generate OFDM Waveform
        % OFDM configuration:
        ofdmMod = comm.OFDMModulator('FFTLength', app.FFTLengthEditField.Value, ...
        'NumGuardBandCarriers', [6;5],...
        'InsertDCNull', app.InsertDCNullCheckBox.Value,...
        'CyclicPrefixLength', app.CyclicPrefixLengthEditField.Value, ...
        'Windowing', app.WindowingCheckBox.Value, ...
        'NumSymbols', app.OFDMSymbolsEditField.Value, ...
        'NumTransmitAntennas', app.TransmitAntennasEditField.Value, ...
        'PilotInputPort', app.PioltInputCheckBox.Value);
        
        M = 4; 	 % Modulation order
        
        % input bit source:
        in = randi([0 1], 10600, 1);
        
        dataInput = qammod(in, M, 'gray', 'InputType', 'bit', 'UnitAveragePower', true);
        ofdmInfo = info(ofdmMod);
        ofdmSize = ofdmInfo.DataInputSize;
        dataInput = reshape(dataInput, ofdmSize);
        
        % waveform generation:
        waveform = ofdmMod(dataInput); % y axis, x axis = time
        
        % Covert time signal to frequency signal then plot to axis
        
        Fs = 6.4 * app.SubcarrierSpacingHzEditField.Value; % sample rate of waveform
        
        %% Visualize OFDM Waveform
        
        % Spectrum Analyzer
        spectrum = dsp.SpectrumAnalyzer('SampleRate', Fs);
        spectrum(waveform);
        release(spectrum);
        
        % OFDM Subcarrier Mapping
        showResourceMapping(ofdmMod);
        
        end

        function test5(app)
            
        %Create Frequency sprectrum of the time signal above
        %n = length(sSum);                          %No of time samples
        %Y = fft(sSum);                             
        %Y = fftshift(Y);                        %zero-centered spectrum
        %fScale = (-n/2:n/2-1)*(fs/n);              % zero-centered frequency range (X-axis)
        %P = (abs(Y).^2/n)/n;                    % zero-centered power (Y-axis)
        
        %plot(app.UIAxesTime, t, sSum);             %Time Domain plot
        %plot(app.UIAxesFreq, fScale(n/2+1:n),P(n/2+1:n));%Frequency domain plot
        
        Fs = 100e6;  % Sampling frequency
        fSz = 5000000;  % Frame size
        
        sin1 = dsp.SineWave(1e0,  5e6,0,'SamplesPerFrame',fSz,'SampleRate',Fs);
        sin2 = dsp.SineWave(1e-1,15e6,0,'SamplesPerFrame',fSz,'SampleRate',Fs);
        sin3 = dsp.SineWave(1e-2,25e6,0,'SamplesPerFrame',fSz,'SampleRate',Fs);
        sin4 = dsp.SineWave(1e-3,35e6,0,'SamplesPerFrame',fSz,'SampleRate',Fs);
        sin5 = dsp.SineWave(1e-4,45e6,0,'SamplesPerFrame',fSz,'SampleRate',Fs);
        
        scope = dsp.SpectrumAnalyzer;
        scope.SampleRate = Fs;
        scope.SpectralAverages = 1;
        scope.PlotAsTwoSidedSpectrum = false;
        scope.RBWSource = 'Auto';
        scope.PowerUnits = 'dBW';
        for idx = 1:1e2 
             y1 = sin1();
             y2 = sin2();
             y3 = sin3();
             y4 = sin4();
             y5 = sin5();
             scope(y1+y2+y3+y4+y5+0.0001*randn(fSz,1));
        end
        release(scope)
        
        %hMod = comm.OFDMModulator;
        %hMod.FFTLength = 128;
        %hMod.NumSymbols = 2;
           
        %disp(hMod)
           
        %showResourceMapping(hMod)
        end
        
        function test6(app)
        
        %% Generate Sine Wave Waveform
        Fs = 1000; 								 % sample rate of waveform
        
        % Sine Wave configuration:
        % waveform configuration:
        sineWaves = dsp.SineWave('Frequency', 100, ...
            'Amplitude', [1], ...
            'PhaseOffset', [0], ...
            'SampleRate', Fs , ...
            'ComplexOutput', 0, ...
            'SamplesPerFrame', 100 *Fs);
        
        % waveform generation:
        waveform = sineWaves();
        
        %% Visualize Sine Wave Waveform
        % Time Scope
        timeScope = dsp.TimeScope('SampleRate', Fs, ...
            'TimeSpanOverrunAction', 'Scroll', ...
            'TimeSpan', 0.03);
        timeScope(waveform);
        release(timeScope);
        
        % Spectrum Analyzer
        spectrum2 = dsp.SpectrumAnalyzer('SampleRate', Fs);
        spectrum2(waveform);
        release(spectrum2);
        end


        function test7(app)
            config = cdma2000ForwardReferenceChannels('TRAFFIC-RC4-4800',5000,'F-SCH-307200-20');
                      
            wv = cdma2000ForwardWaveformGenerator(config);
            fs = 1.2288e6 * config.OversamplingRatio;
            
            sa = dsp.SpectrumAnalyzer('SampleRate',fs);
            step(sa,wv);

        end
         
        function test8(app)
            sine1 = dsp.SineWave();
            sine1.Amplitude = 2;
            sine1.Frequency = 50;
            sine1.PhaseOffset = 0 * pi/2;
            sine1.SamplesPerFrame = 100;
            y = sine1();
            plot(app.UIAxes , y)
            
            sine2 = dsp.SineWave();
            sine2.Amplitude = 4;
            sine2.Frequency = 10;
            sine2.PhaseOffset = 0 * pi/2;
            sine2.SamplesPerFrame = 5;
            y = sine2();
            
            sine3 = dsp.SineWave();
            sine3.Amplitude = 4;
            sine3.Frequency = 10;
            sine3.PhaseOffset = 0 * pi/2;
            sine3.SamplesPerFrame = 5;
            y = sine3();
            
            sine4 = dsp.SineWave();
            sine4.Amplitude = 4;
            sine4.Frequency = 10;
            sine4.PhaseOffset = 0 * pi/2;
            sine4.SamplesPerFrame = 5;
            y = sine4();
            
            sine5 = dsp.SineWave();
            sine5.Amplitude = 4;
            sine5.Frequency = 10;
            sine5.PhaseOffset = 0 * pi/2;
            sine5.SamplesPerFrame = 5;
            y = sine5();
            
            %sSum=sine1+sine2+sine3+sine4+sine5;
            
            %plot(app.UIAxes , y, sSum);
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.test4()
        end

        % Button pushed function: genButton
        function genButtonPushed(app, event)
             app.test7()
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.test8()
        end

        % Value changed function: Signal2CheckBox
        function Signal2CheckBoxValueChanged(app, event)
            updateplot(app);
        end

        % Value changed function: Signal3CheckBox
        function Signal3CheckBoxValueChanged(app, event)
            updateplot(app);
        end

        % Value changed function: Signal4CheckBox
        function Signal4CheckBoxValueChanged(app, event)
            updateplot(app);
        end

        % Value changed function: Signal1CheckBox
        function Signal1CheckBoxValueChanged(app, event)
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AirInterface5GLabel = uilabel(app.UIFigure);
            app.AirInterface5GLabel.Tooltip = {'Youssef Alchaer'; 'Anotheny Vellone'};
            app.AirInterface5GLabel.Text = 'Air Interface 5G';
            app.OFDMPanel = uipanel(app.UIFigure);
            app.OFDMPanel.Title = 'OFDM';
            app.GenerateButton = uibutton(app.OFDMPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.InsertDCNullCheckBox = uicheckbox(app.OFDMPanel);
            app.InsertDCNullCheckBox.Text = 'Insert DC Null';
            app.WindowingCheckBox = uicheckbox(app.OFDMPanel);
            app.WindowingCheckBox.Text = 'Windowing';
            app.PioltInputCheckBox = uicheckbox(app.OFDMPanel);
            app.PioltInputCheckBox.Text = 'Piolt Input';
            app.FFTLengthEditFieldLabel = uilabel(app.OFDMPanel);
            app.FFTLengthEditFieldLabel.Text = 'FFT Length';
            app.FFTLengthEditField = uieditfield(app.OFDMPanel, 'numeric');
            app.FFTLengthEditField.Value = 64;
            app.CyclicPrefixLengthEditFieldLabel = uilabel(app.OFDMPanel);
            app.CyclicPrefixLengthEditFieldLabel.Text = 'Cyclic Prefix Length';
            app.CyclicPrefixLengthEditField = uieditfield(app.OFDMPanel, 'numeric');
            app.CyclicPrefixLengthEditField.Value = 16;
            app.OFDMSymbolsEditFieldLabel = uilabel(app.OFDMPanel);
            app.OFDMSymbolsEditFieldLabel.Text = 'OFDM Symbols';
            app.OFDMSymbolsEditField = uieditfield(app.OFDMPanel, 'numeric');
            app.OFDMSymbolsEditField.Value = 100;
            app.TransmitAntennasEditFieldLabel = uilabel(app.OFDMPanel);
            app.TransmitAntennasEditFieldLabel.Text = 'Transmit Antennas';
            app.TransmitAntennasEditField = uieditfield(app.OFDMPanel, 'numeric');
            app.TransmitAntennasEditField.Value = 1;
            app.SubcarrierSpacingHzEditFieldLabel = uilabel(app.OFDMPanel);
            app.SubcarrierSpacingHzEditFieldLabel.Text = 'Subcarrier Spacing (Hz)';
            app.SubcarrierSpacingHzEditField = uieditfield(app.OFDMPanel, 'numeric');
            app.SubcarrierSpacingHzEditField.Value = 100000;
            app.OFDMModelPanel = uipanel(app.UIFigure);
            app.OFDMModelPanel.Title = 'OFDM Model';
            app.AddSignalDisruptionPanel = uipanel(app.UIFigure);
            app.AddSignalDisruptionPanel.Title = 'Add Signal Disruption';
            app.Signal1CheckBox = uicheckbox(app.AddSignalDisruptionPanel);
            app.Signal1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Signal1CheckBoxValueChanged, true);
            app.Signal1CheckBox.Text = 'Signal 1';
            app.Signal2CheckBox = uicheckbox(app.AddSignalDisruptionPanel);
            app.Signal2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Signal2CheckBoxValueChanged, true);
            app.Signal2CheckBox.Text = 'Signal 2';
            app.Signal3CheckBox = uicheckbox(app.AddSignalDisruptionPanel);
            app.Signal3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Signal3CheckBoxValueChanged, true);
            app.Signal3CheckBox.Text = 'Signal 3';
            app.Signal4CheckBox = uicheckbox(app.AddSignalDisruptionPanel);
            app.Signal4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Signal4CheckBoxValueChanged, true);
            app.Signal4CheckBox.Text = 'Signal 4';
            app.Button = uibutton(app.AddSignalDisruptionPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.CDMAPanel = uipanel(app.UIFigure);
            app.CDMAPanel.Title = 'CDMA';
            app.genButton = uibutton(app.CDMAPanel, 'push');
            app.genButton.ButtonPushedFcn = createCallbackFcn(app, @genButtonPushed, true);
            app.genButton.Text = 'gen';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Air_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end